classdef FunctionPlotterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FrequencySlider       matlab.ui.control.Slider
        FrequencySliderLabel  matlab.ui.control.Label
        FrequencyLabel        matlab.ui.control.Label
        FunctionButtonGroup   matlab.ui.container.ButtonGroup
        CosButton             matlab.ui.control.RadioButton
        SinButton             matlab.ui.control.RadioButton
        GenerateButton        matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Generator
        Listener
    end
    
    methods (Access = private)
        
        function onNewData(app, ~, eventData)
            % Update the plot with new signal data
            plot(app.UIAxes, eventData.Time, eventData.value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create the signal generator
            app.Generator = src.SignalGenerator();

            % Listen for new data
            app.Listener = addlistener(app.Generator, 'NewDataReady', @app.onNewData);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
             % Get frequency from slider
            app.Generator.frequency = app.FrequencySlider.Value;
            
            % Get function type from button group
            app.Generator.funcType = lower(app.FunctionButtonGroup.SelectedObject.Text(1:3));
            
            % Generate the signal
            app.Generator.generate();
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
             app.Generator.frequency = app.FrequencySlider.Value;
             app.FrequencyLabel.Text = sprintf('Frequency: %.0f Hz', app.FrequencySlider.Value);
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            app.Generator.frequency = event.Value;  % event.Value au lieu de app.FrequencySlider.Value
            app.FrequencyLabel.Text = sprintf('Frequency: %.0f Hz', event.Value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'GenerateButton';
            app.FunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.FunctionButtonGroup.Title = 'FunctionButtonGroup';
            app.SinButton = uiradiobutton(app.FunctionButtonGroup);
            app.SinButton.Text = 'SinButton';
            app.SinButton.Value = true;
            app.CosButton = uiradiobutton(app.FunctionButtonGroup);
            app.CosButton.Text = 'CosButton';
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'FrequencyLabel';
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'FrequencySlider';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [0 10];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencySlider.Step = 1;
            app.FrequencySlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FunctionPlotterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end